classdef Image_Positions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        CalculatePositionsButton       matlab.ui.control.Button
        RadiusofPhantomcmEditField     matlab.ui.control.NumericEditField
        RadiusofPhantomcmEditFieldLabel  matlab.ui.control.Label
        HowManyAntennasEditField       matlab.ui.control.NumericEditField
        HowManyAntennasEditFieldLabel  matlab.ui.control.Label
        AddAntennaPositionsButton      matlab.ui.control.Button
        AddGlandPositionButton         matlab.ui.control.Button
        AddTumorPositionButton         matlab.ui.control.Button
        AddPhantomPositionButton       matlab.ui.control.Button
        EnterImageFileEditField        matlab.ui.control.EditField
        EnterImageFileEditFieldLabel   matlab.ui.control.Label
        PhantomPotisionsLabel          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        phantomPosition
        tumorPosition
        glandPosition
        antennaPositions
        phantomCenter
        phantomRadius
        tumorCenter
        tumorRadius
        glandCenter
        glandRadius
        imageHandle 
    end
    
    methods (Access = private)
        
        function updateROI(app, src, typeName)
            index = src.UserData;
            app.([typeName, 'Center'])(index, :) = src.Center;
            app.([typeName, 'Radius'])(index) = src.Radius;
        end

        function updateAntenna(app, src)
            index = src.UserData;
            app.antennaPositions(index, :) = src.Position;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EnterImageFileEditField
        function EnterImageFileEditFieldValueChanged(app, event)
        enteredPath = app.EnterImageFileEditField.Value;
    
        if isfile(enteredPath)
            newImg = imread(enteredPath);
            
            
            if isempty(app.imageHandle) || ~isvalid(app.imageHandle)
                app.imageHandle = imshow(newImg, 'Parent', app.UIAxes);
                axis(app.UIAxes, 'tight');
                axis(app.UIAxes, 'image');
            else
                app.imageHandle.CData = newImg;
                    app.UIAxes.XLim = [0.5, size(newImg, 2) + 0.5];
                    app.UIAxes.YLim = [0.5, size(newImg, 1) + 0.5];
            end
        else
            cla(app.UIAxes);
            app.imageHandle = []; 
            uialert(app.UIFigure, 'The file path provided does not exist.', 'Invalid File');
        end

        end

        % Button pushed function: AddPhantomPositionButton
        function AddPhantomPositionButtonPushed(app, event)
            roiPhantom = drawcircle(app.UIAxes,'color','red', 'FaceAlpha', 0);
            roiPhantom.UserData = 1;
            app.phantomCenter = roiPhantom.Center;
            app.phantomRadius = roiPhantom.Radius;
            addlistener(roiPhantom, 'ROIMoved', @(src, evt) app.updateROI(src,'phantom'));

        end

        % Button pushed function: AddTumorPositionButton
        function AddTumorPositionButtonPushed(app, event)
            roiTumor = drawcircle(app.UIAxes,'color','yellow', 'FaceAlpha', 0);
            roiTumor.UserData = 1;
            app.tumorCenter = roiTumor.Center;
            app.tumorRadius = roiTumor.Radius;
            addlistener(roiTumor, 'ROIMoved', @(src, evt) app.updateROI(src,'tumor'));

        end

        % Button pushed function: AddGlandPositionButton
        function AddGlandPositionButtonPushed(app, event)
            roiGland = drawcircle(app.UIAxes,'color','green', 'FaceAlpha', 0);
            roiGland.UserData = 1;
            app.glandCenter = roiGland.Center;
            app.glandRadius = roiGland.Radius;
            addlistener(roiGland, 'ROIMoved', @(src, evt) app.updateROI(src,'gland'));

        end

        % Button pushed function: AddAntennaPositionsButton
        function AddAntennaPositionsButtonPushed(app, event)
            numberOfAntennas = app.HowManyAntennasEditField.Value; 
            app.antennaPositions = zeros(numberOfAntennas, 2);

            for i = 1:numberOfAntennas
                roiAntenna = drawpoint(app.UIAxes);
                roiAntenna.UserData = i;
                app.antennaPositions(i,:) = roiAntenna.Position; 
                addlistener(roiAntenna, 'ROIMoved', @(src, evt) app.updateAntenna(src,'antennaPositions'));
            end
        end

        % Button pushed function: CalculatePositionsButton
        function CalculatePositionsButtonPushed(app, event)
            physicalRadius = app.RadiusofPhantomcmEditField.Value;

            positionFromPhoto(app.phantomCenter, app.phantomRadius, ...
                app.tumorCenter, app.tumorRadius, ...
                app.glandCenter, app.glandRadius, ...
                app.antennaPositions, ...
                physicalRadius)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PhantomPotisionsLabel = uilabel(app.LeftPanel);
            app.PhantomPotisionsLabel.Text = 'Phantom Potisions';
            app.EnterImageFileEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterImageFileEditFieldLabel.Text = 'Enter Image File';
            app.EnterImageFileEditField = uieditfield(app.LeftPanel, 'text');
            app.EnterImageFileEditField.ValueChangedFcn = createCallbackFcn(app, @EnterImageFileEditFieldValueChanged, true);
            app.AddPhantomPositionButton = uibutton(app.LeftPanel, 'push');
            app.AddPhantomPositionButton.ButtonPushedFcn = createCallbackFcn(app, @AddPhantomPositionButtonPushed, true);
            app.AddPhantomPositionButton.Text = 'Add Phantom Position';
            app.AddTumorPositionButton = uibutton(app.LeftPanel, 'push');
            app.AddTumorPositionButton.ButtonPushedFcn = createCallbackFcn(app, @AddTumorPositionButtonPushed, true);
            app.AddTumorPositionButton.Text = 'Add Tumor Position';
            app.AddGlandPositionButton = uibutton(app.LeftPanel, 'push');
            app.AddGlandPositionButton.ButtonPushedFcn = createCallbackFcn(app, @AddGlandPositionButtonPushed, true);
            app.AddGlandPositionButton.Text = 'Add Gland Position';
            app.AddAntennaPositionsButton = uibutton(app.LeftPanel, 'push');
            app.AddAntennaPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @AddAntennaPositionsButtonPushed, true);
            app.AddAntennaPositionsButton.Text = 'Add Antenna Positions';
            app.HowManyAntennasEditFieldLabel = uilabel(app.LeftPanel);
            app.HowManyAntennasEditFieldLabel.Text = 'How Many Antennas';
            app.HowManyAntennasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HowManyAntennasEditField.Value = 8;
            app.RadiusofPhantomcmEditFieldLabel = uilabel(app.LeftPanel);
            app.RadiusofPhantomcmEditFieldLabel.Text = 'Radius of Phantom (cm)';
            app.RadiusofPhantomcmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RadiusofPhantomcmEditField.Value = 7.3;
            app.CalculatePositionsButton = uibutton(app.LeftPanel, 'push');
            app.CalculatePositionsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePositionsButtonPushed, true);
            app.CalculatePositionsButton.Text = 'Calculate Positions';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Positions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end